classdef fcsSOFI_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FilenameextEditFieldLabel      matlab.ui.control.Label
        TrialnumbersEditFieldLabel     matlab.ui.control.Label
        FilenameextEditField           matlab.ui.control.EditField
        TrialnumbersEditField          matlab.ui.control.NumericEditField
        PixelsizenmEditFieldLabel      matlab.ui.control.Label
        PixelsizenmEditField           matlab.ui.control.NumericEditField
        dTsEditFieldLabel              matlab.ui.control.Label
        dTsEditField                   matlab.ui.control.NumericEditField
        xminEditFieldLabel             matlab.ui.control.Label
        xminEditField                  matlab.ui.control.NumericEditField
        xmaxEditFieldLabel             matlab.ui.control.Label
        xmaxEditField                  matlab.ui.control.NumericEditField
        yminEditField_3Label           matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        ymaxEditField                  matlab.ui.control.NumericEditField
        tminEditFieldLabel             matlab.ui.control.Label
        tminEditField                  matlab.ui.control.NumericEditField
        tmaxEditFieldLabel             matlab.ui.control.Label
        tmaxEditField                  matlab.ui.control.NumericEditField
        DiffusionModelButtonGroup      matlab.ui.container.ButtonGroup
        BrownianButton                 matlab.ui.control.ToggleButton
        CompBrownianButton             matlab.ui.control.ToggleButton
        AnomalousButton                matlab.ui.control.ToggleButton
        DisplayfiguresButtonGroup      matlab.ui.container.ButtonGroup
        YesButton                      matlab.ui.control.ToggleButton
        NoButton                       matlab.ui.control.ToggleButton
        SavedataButtonGroup            matlab.ui.container.ButtonGroup
        YesButton_2                    matlab.ui.control.ToggleButton
        NoButton_2                     matlab.ui.control.ToggleButton
        MicroscopyParametersLabel      matlab.ui.control.Label
        RegionofInterestinPixelsLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        fcsSOFILabel                   matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        LoadDataLabel                  matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        DisplayROIButton               matlab.ui.control.Button
        DisplayFigureDropDownLabel     matlab.ui.control.Label
        DisplayFigureDropDown          matlab.ui.control.DropDown
        ProgressTextArea               matlab.ui.control.TextArea
        BrowseButton                   matlab.ui.control.Button
        ManualLoadLabel                matlab.ui.control.Label
        RowIndexEditFieldLabel         matlab.ui.control.Label
        RowIndexEditField              matlab.ui.control.NumericEditField
        ColumnIndexEditFieldLabel      matlab.ui.control.Label
        ColumnIndexEditField           matlab.ui.control.NumericEditField
        DisplaySinglePixelCurveFitButton  matlab.ui.control.Button
        SinglePixelFitResultsLabel     matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SinglePixelTextArea            matlab.ui.control.TextArea
        Version1630March2020Label      matlab.ui.control.Label
        minEditFieldLabel              matlab.ui.control.Label
        cminEditField                  matlab.ui.control.EditField
        maxEditFieldLabel              matlab.ui.control.Label
        cmaxEditField                  matlab.ui.control.EditField
        CaxisLimitsfcsSOFIColorScalingLabel  matlab.ui.control.Label
        SOFISaturationLimitsLabel      matlab.ui.control.Label
        minEditField_2Label            matlab.ui.control.Label
        cminACEditField                matlab.ui.control.EditField
        maxEditField_2Label            matlab.ui.control.Label
        cmaxACEditField                matlab.ui.control.EditField
        ModelParameterSettingsLabel    matlab.ui.control.Label
        alphastartpointLabel           matlab.ui.control.Label
        alpha_stpEditField             matlab.ui.control.EditField
        maxiumalphaallowedLabel        matlab.ui.control.Label
        alpha_threshEditField          matlab.ui.control.EditField
        SOFIresponsethresholdLabel     matlab.ui.control.Label
        SOFI_threshEditField           matlab.ui.control.EditField
        EditField_7Label_2             matlab.ui.control.Label
        td_stpEditField                matlab.ui.control.EditField
        EditField_7Label_3             matlab.ui.control.Label
        td2_stpEditField               matlab.ui.control.EditField
        AlphaMapSettingsLabel          matlab.ui.control.Label
        AnomalousmodelonlyLabel        matlab.ui.control.Label
        CompmodelonlyLabel             matlab.ui.control.Label
        RedrawfcsSOFIButton            matlab.ui.control.Button
        FWHMofPSFpixelsLabel           matlab.ui.control.Label
        FWHMEditField                  matlab.ui.control.NumericEditField
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% properties
properties (Access = private)
    parameter = 'dataset'; %generic data set file name extension
    trialnos=[77]; %data set number (can analyze multiple data sets by entering multiple numbers)

    %diffusion coefficient parameters
    pixelsize=50; %in nm; needed to accurately calculate D
    dT=0.04; %in s; needed to accurately calculate D
    FWHM = 2.7;

    %caxis scale  %for simulated data
    minScale = 0;
    maxScale = 15000;

    %region of interest in pixels
    xmin=1; xmax=30;
    ymin=1; ymax=30;
    tmin = 1; tmax= 5000;
    
    %choose type of diffusion (1 = Brownian, 2 = 2-Comp Brownian, 3 = Anomalous)
    type = 1;
    
    % tau_D start point (2-comp diffusion model only)
    td_stp = 0.3678795;
    td2_stp = 1/2*0.3678795;
   
    %choose alpha start point (Anomalous diffusion model only)
    alpha_stp = 1;
    
    %alpha threshold
    alpha_thresh = 2; % maximum value of alpha allowed to appear on alpha map

    %SOFI response threshold
    SOFI_thresh = 0.01;
    
    %set SOFI saturation limits
    cminAC=0; % line 853 in GUI code
    cmaxAC=1e9;

    %set caxis limits for fcsSOFI diffusion map (color scaling)
    cmin=2; %line
    cmax=6;

    %plot figures? (1 = yes)
    plotfigures=1;

    %save super-res image, Rsquare map, and combined figures? 
    savefigures = 1;

    %save data files? (1 = yes)
    savethedata=1; 

    %number of fits per pixel
    number_fits = 1000;

    %single pixel curve fit plot
    row_index = 15; %pixel row index
    column_index = 15; %pixel column index
    
    browse;
    browsed_file;
    
    date;
    normcoef;
    converged_parameters;
    avgimage;
    DataCombined;
    hsv2rgbmap;
    rgb_cmap_rs;
    R2map;
    srmap_hsv;
    Dmap;
    D2map;
    Dmap_corrected;
    D2map_corrected
    Dmap2log;
    D2map2log;
    Dmap_hsv;
    normDmap2log;
    alphamap;
    fitresult2;
    totaltime;
    
    szmap1;
    szmap2;
    filtim;
    norm_ca_AC;
    
    fname;


end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% end properties/begin methods

methods (Access = private)
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CalcCorr function
    
    function [ACXC_all] = CalcCorr(app,innerpts,DataVector,c,L)
        %i range accomodates time offset
        i = 1;
        for i = 1:length(innerpts)-2        
            %taking only indices we care about
            index = innerpts(i);
            %Fluorescence intensity calculations        
            %fluorescence fluctuation
            F = DataVector(:,index);
            dF = F - mean(F);

            %dF of all surrounding points
            dFD = DataVector(:, index+1)- mean(DataVector(:, index+1));
            dFR = DataVector(:, index+L+1)- mean(DataVector(:, index+L+1));
            dFDR = DataVector(:, index+L+2)- mean(DataVector(:, index+L+2));

            %% Perform AC, XC calculations
            %autocorrelating and cross-correlating with xcorr
            [AC, lags] = xcorr(dF, dF); 

            %min_lags staring at zero
            min_lags = (length(lags)-1)/2+1;
            max_lags=length(lags);

            %fluctuations about time offset
            F2 = DataVector(:, index+1);
            dF2 = F2-mean(F2);
            F3 = DataVector(:, index+2);
            dF3 = F3- mean(F3);      

            % 2nd order correlation
            [corrCombined2]=rcSOFI_2ndOrder(app,AC,  min_lags,max_lags);        

            %LK-add storing the AC and xcorr curves for each pixel, i
            ACXC_all(1,i)=struct('Order2',corrCombined2);
            i = i+1;
        end   
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% rotgauss function

   function val = rotgauss(app,x, y, theta, sigmax, sigmay, offset, factor, center)
        xc      = center(1);
        yc      = center(2);
        theta   = (theta/180)*pi;
        xm      = (x-xc)*cos(theta) - (y-yc)*sin(theta);
        ym      = (x-xc)*sin(theta) + (y-yc)*cos(theta);
        u       = (xm/sigmax)^2 + (ym/sigmay)^2;
        val     = offset + factor*exp(-u/2);
   end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% customgauss function
        
    function ret = customgauss(app,gsize, sigmax, sigmay, theta, offset, factor, center)
        ret     = zeros(gsize);
        rbegin  = -round(gsize(1) / 2);
        cbegin  = -round(gsize(2) / 2);
        for r=1:gsize(1)
        for c=1:gsize(2)
            ret(r,c) = rotgauss(app,rbegin+r,cbegin+c, theta, sigmax, sigmay, offset, factor, center);
        end
        end
    end
        

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% getUpperLeft function
        
    function [selectedpts] = getUpperLeft(app,length, width, startingpt)
        selectedpts = [];
        for n = 1:width-1
            newpts = startingpt+(n-1)*length:startingpt+n*length-2;
            selectedpts = [selectedpts newpts];
            n = n+1;
        end
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% logbindata function
        
    function [new_lags, new_AC] = logbindata(app,lags,AC_aver,ddwell,max_lag)
        % determine the lowest and higher order
        low_flag = 0;
        hi_flag = 0;
        
        for i=1:10 
            if (ddwell/10^(-i) >= 0.999) && low_flag == 0
                low_order = -i; % find the lowest order of magnitude in the time scale
                low_flag = 1;
            end
            
            if max_lag > 1
                if (max_lag/10^(i) <= 1) && hi_flag ==0
                    hi_order = i; % find the highest order of magnitude in the time scale
                    hi_flag = 1;
                end
            
            elseif max_lag < 1 % in case the max time is less than 1 s
                if (max_lag/10^(-i) >= 0.9999) && hi_flag ==0
                    hi_order = -i+1; % find the highest order of magnitude in the time scale
                    hi_flag = 1;
                end
            
            else
            fprintf('\noops, something is wrong with max_lag');
            fprintf('\nexiting');
            end
        end

        % ['low_order', 'hi_order']
        [low_order, hi_order];

        % compute the number of bin before the next order
        n_first_bins = int32((10^(low_order+1))/(lags(1)*ddwell)) - 1;
    
        % compute the total number of bins
        nbins = n_first_bins + 9*(hi_order - (low_order + 1));
    
        % return
        new_lags = zeros(nbins,1);
        new_AC = zeros(nbins,1);
        npoints_per_bin = zeros(hi_order-(low_order+1),1);
    
        % fill the bins until the next order
        for ipoint=1:n_first_bins
            new_lags(ipoint) = lags(ipoint);
            new_AC(ipoint) = AC_aver(ipoint);
        end
    
        flag_stop = 0; % is used to stop filling the bins once the end of lags array is reached
    
    
        % start the loop to fill the rest of the bins
        for iorder = 1:(hi_order-(low_order+1))
            order = double(low_order + iorder); % else it can't do 10^(-int)
            npoints_per_bin(iorder) = int32(10.0^order/(lags(1)*ddwell));
        
            % loop over bins inside the order
            for ibin = 1:9
                bin_indx = n_first_bins + (iorder-1)*9 + ibin;
                
                for ipoint = 1:npoints_per_bin(iorder)
                        tmp_indx = n_first_bins;
                    for jorder = 1:(iorder-1)
                        tmp_indx = tmp_indx + 9*npoints_per_bin(jorder);
                    end
                        tmp_indx = tmp_indx + (ibin-1)*npoints_per_bin(iorder) + ipoint;
                        
                        
                    if (tmp_indx < length(lags))
                        point_indx = tmp_indx;
                    else
                        flag_stop  = 1;
                        break
                    end
                        
                    new_lags(bin_indx) = new_lags(bin_indx) + lags(point_indx)/npoints_per_bin(iorder);
                    new_AC(bin_indx) = new_AC(bin_indx) + AC_aver(point_indx)/npoints_per_bin(iorder);
                        
                end %loop over points
          
                if (flag_stop == 1)
                    break
                end
             end % loop over bins
                    
            if (flag_stop == 1)
                break
            end
            
         end % loop over orders
    
    
         % rid new_lags of zero values
         ind = find(new_lags);
         tmp = new_lags(ind);
         new_lags = tmp; 
         tmp = new_AC(ind);
         new_AC = tmp;
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% rcSOFI_2ndOrder function

    function [corrCombined] = rcSOFI_2ndOrder(app,AC,  min_lags,max_lags,lags)
         % 2nd order correlation
        AC2 = fft(AC(min_lags:max_lags)); %2nd order
        
        %out of Fourier space
        AC2 = ifft(AC2);       

        % 4X499 correlation data
        corrCombined = [AC2'];

    end
        

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% gof_stats function

    function [rsq,chisq,J,MSE,ci] = gof_stats(app,type,parameters,fitresult,x,y)
    
    %% Residuals
    residuals = y - fitresult;
    a = (y - fitresult).^2./fitresult;
    a(isinf(a)) = 0;
    
    %% Chi-square
    chisq = sum(a);
    
    %% R-square
    rsq = 1-sum(residuals.^2)/sum(y.^2);
    
    %% Partial Derivatives and Jacobian Matrix
    % Brownian
    if type == 1
        % partial derivatives
        dGda = parameters(3)./(parameters(3)+x);
        dGdb = ones(size(x));
        dGdtau = (parameters(1).*x)./(parameters(3)+x).^2;
        
        % Jacobian
        J = [dGda dGdb dGdtau];J = reshape(J,[length(x),length(parameters)]);
        
    % 2-Componenet Brownian
    elseif type == 2
        % partial derivatives
        dGda1 = parameters(4)./(parameters(4)+x);
        dGda2 = parameters(5)./(parameters(5)+x);
        dGdb = ones(size(x));
        dGdtau1 = (parameters(1).*x)./(parameters(4)+x).^2;
        dGdtau2 = (parameters(2).*x)./(parameters(5)+x).^2;
        
        % Jacobian
        J = [dGda1 dGda2 dGdb dGdtau1 dGdtau2];J = reshape(J,[length(x),length(parameters)]);
        
    % Anomalous
    elseif type == 3
        %partial derivatives
        dGda = 1./((x./parameters(3)).^(parameters(4)+1));
        dGdb = ones(size(x));
        dGdtau = parameters(1)*parameters(4).*(x./parameters(3)).^(parameters(4))./(parameters(3).*((x/parameters(3)).^(parameters(4))).^2);
        dGdalpha = (parameters(1)*(x./parameters(3)).^(parameters(4)).*log(x./parameters(3)))./((x./parameters(3)).^(parameters(4)+1)).^2;
    
        % Jacobian
        J = [dGda dGdb dGdtau dGdalpha];J = reshape(J,[length(x),length(parameters)]);
        
    end
    %% Covariance matrix
    
    %Mean squared error
    MSE = sum((y - fitresult).^2)/length(y); 
    
    % ci = 1;
    %% Confidence intervals
    disp(size(parameters))
    disp(size(residuals))
    disp(size(J))
    ci = nlparci(parameters,residuals,'jacobian',J);
    
    end

end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% end Methods

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ProgressTextArea, RunButton
        function RunButtonPushed(app, event)
            clc; close all;
            
            %record time of trial
            app.date = datestr(now,'mm-dd-yyyy_HH-MM');
            
            % start timing fcsSOFI execution
            before = clock;
            
            % add Gpufit to path
            addpath(strcat(pwd,'\Gpufit_build-64_20190709\Debug\matlab')) %gpufit build
            
%%%%%%%%%%%%%%%%%%%% STEP 1: blink_AConly (SOFI) %%%%%%%%%%%%%%%%%%%%%%%%%
            % start timing SOFI execution
            blink_before = clock;
            
            app.ProgressTextArea.Value = sprintf('Running...');
            drawnow
            
            for j = 1:numel(app.trialnos)
                %% %%%%%%%%%%%%%%%% start code/load and setup data %%%%%%%%%%%%%%%%%%%%
                
                if app.browse == 1
                    app.fname = app.browsed_file;
                else
                    app.fname = strcat(app.parameter,num2str(app.trialnos(j)));
                end
                simpath=[app.fname];
            
                %determine length of trajectory
                intlength = app.tmax-app.tmin+1;
            
                % Load data
                load(app.fname); fprintf(strcat('\n',app.fname,' loaded\n')); drawnow;
                app.DataCombined=Data;
            
                % Set ROI
                app.DataCombined=app.DataCombined(app.ymin:app.ymax,app.xmin:app.xmax,app.tmin:app.tmax);
                size(app.DataCombined);
            
                % produce average image
                app.avgimage=sum(app.DataCombined(:,:,:),3)./size(app.DataCombined,3);
            
                % Reshape 2D image to 1D for analysis
                % shapes columnwise into a 1x(app.ymax-app.ymin+1)(app.xmax-app.xmin+1)
                DataVector = zeros(app.tmax,(app.xmax-app.xmin+1)*(app.ymax-app.ymin+1));
                for i=1:size(app.DataCombined,3)
                    DataVector(i,:)=reshape(app.DataCombined(:,:,i),1,size(app.DataCombined(:,:,i),1)*...
                        size(app.DataCombined(:,:,i),2));
                end
            
                % part of distance factor-- c = sigma    
                  c = 3.5; %experimentally based
            
                % only upper left points are run through analysis to avoid repetition
                [innerpts] = getUpperLeft(app,app.ymax-app.ymin+1, app.xmax-app.xmin+1, 1);
                L = app.ymax-app.ymin;
                W = app.xmax-app.xmin;
            
                %% %%%%%%%%%% Calculate the correlation (2-4th orders, AC and XC) %%%%%% %%
                rcSOFItime=tic;
                [ACXC_all]=CalcCorr(app,innerpts,DataVector,c,L); %calculate 
            
            
                %% %%%%%%%%%% Calculate intensity for images by different methods %%%%%%%%      
                AC_G2 = zeros(1,numel(ACXC_all));
                for i=1:numel(ACXC_all)
                    % AC only - first point of different orders
                    AC_G2(i) = ACXC_all(1,i).Order2(1,2);
                end
            
                %% %%%%%%%%%%%%%%%%%%% Reshape matrices for images %%%%%%%%%%%%%%%%%%%%% %%
                % reshape easy ones first
                AC_G2_im=[0,AC_G2,0];% pad matrices with first points, so back to original size
                AC_G2_im=reshape(AC_G2_im,L,W);% reshape
            
                %% Deconvolution
                avgim=app.avgimage;
                im=AC_G2_im;
                % define the PSF 

%               app.FWHM=2.7; %FWHM of PSF in pixels
                intensity = 1;
                gauss1=customgauss(app,[100 100],app.FWHM,app.FWHM,0,0,intensity,[5 5]); %create a 2D PSF
                PSF=gauss1(45:65,45:65); %only use the center where the PSF is located at
            
                app.filtim=deconvlucy(im,PSF); % Based on Geissbuehler bSOFI paper
                
            end
            blink_after = clock;
              app.ProgressTextArea.Value = sprintf('SOFI complete');
              drawnow
%%%%%%%%%%%%%%%%%%%% STEP 2: BinFitData (fcs) %%%%%%%%%%%%%%%%%%%%%%%%%
            
            % can we parallelize this further? MATLAB packages?
            for index = 1:numel(app.trialnos)
            % load(strcat(app.parameter,num2str(app.trialnos(index)),'analyzed.mat'));
            Bin_before = clock;
            
            % ROI pixels of image to analyze
            xmn=1; xmx=app.xmax-app.xmin;
            ymn=1; ymx=app.ymax-app.ymin;

            
            %% load data 
            
            uplind=sub2ind(size(AC_G2_im),ymn,xmn);
            uprind=sub2ind(size(AC_G2_im),ymn,xmx);
            dwlind=sub2ind(size(AC_G2_im),ymx,xmn);
            dwrind=sub2ind(size(AC_G2_im),ymx,xmx);
                
            %reshape ACXC_all to get ROI
            ACXC_all2=[ACXC_all,ACXC_all(1,1),ACXC_all(1,1)];
            ACXC_all_reshape=reshape(ACXC_all2,size(AC_G2_im));
            ACXC_all_ROI=ACXC_all_reshape(ymn:ymx,xmn:xmx);
            AC_XC_all_save=reshape(ACXC_all_ROI,1,size(ACXC_all_ROI,1)*size(ACXC_all_ROI,2));
            AC_all(1,1).curves=AC_XC_all_save;
            
            for i=1:numel(AC_all(1,1).curves)
                    % add raw AC curves
                ACadd(1,:)=AC_all(1,1).curves(1,i).Order2;
                AC_avg(1,i).curves=mean(ACadd,1);   
            end
            
            %% log bin the averaged data
            for i=1:numel(AC_all(1,1).curves)
                
                AC_aver=(AC_avg(1,i).curves);
                max_lag=numel(AC_avg(1,i).curves);
                lags=1:max_lag;
                ddwell=1;
            
                [new_lags, new_AC] = logbindata(app,lags,AC_aver,ddwell,max_lag);
                
                AC_logbin(i,:)=new_AC;
                AC_loglag(i,:)=new_lags;
                
            end
            
            % take first lag point to create super-resolution image
            AC_im = zeros(1,size(AC_logbin,1));
            for j=1:size(AC_logbin,1)
                AC_im(j)=AC_logbin(j,2);
            end
            
            rowdim=size(ACXC_all_ROI,1);
            coldim=size(ACXC_all_ROI,2);
            
            AC_im2=reshape([AC_im],rowdim,coldim);
            
            %% Set up curve fitting
            
            % initialize variable to keep track of GPU computation time
            fit_time = 0;
            
            % number of parameters
            number_parameters = [3; 5; 4]; number_parameters = number_parameters(app.type);
            
            % estimator id
            estimator_id = EstimatorID.LSE;
                
            % model ID
            model_id = [ModelID.BROWNIAN_1COMP; ModelID.BROWNIAN_2COMP; ModelID.ANOMALOUS]; model_id = model_id(app.type);
            
            % tolerance
            tolerance = 1e-3;
            
            % maximum number of iterations per pixel
            max_n_iterations = 1000;
            
            % preallocate variables 
            tauD = zeros(1,xmx*ymx); tauD2 = tauD; D = tauD; D2 = tauD; alpha = tauD;
            
            %% Perform curve fitting
            for i=1:size(AC_logbin,1)
                
                if i == ceil(size(AC_logbin,1)/4)
                    app.ProgressTextArea.Value = sprintf('Curve-fitting 25%% complete');
                    drawnow
                end
                if i == ceil(size(AC_logbin,1)/2)
                    app.ProgressTextArea.Value = sprintf('Curve-fitting 50%% complete');
                    drawnow
                end
                if i == ceil(3/4*size(AC_logbin,1))
                    app.ProgressTextArea.Value = sprintf('Curve-fitting 75%% complete');
                    drawnow
                end
                
                
                ACcurve=AC_logbin(i,:);
                timelag=AC_loglag(i,:);     
                
                x=timelag.*app.dT; %convert x values to seconds   
                y=ACcurve;
                
                %remove first timelag point tau=lag
                ind=numel(x);
                x=x(2:ind);
                y=y(2:ind);
                
            %     %fit y vs. log(x) with an exponential to get startpoint for tauD
            %     [parameters_exp, ~, ~, ~, ~] = gpufit(single(y'), [], ...
            %         ModelID.EXPONENTIAL, single([max(y); -1]), 1e-3, 1, [], EstimatorID.LSE, single(log(x')));
            %     td_stp=exp(parameters_exp(2));
            
                
               %declare start points based on diffusion app.type
                sp_struct = struct; % start point structure
                sp_struct.brownian = [max(y)*2,mean(y(round((3*numel(y)/4)):numel(y))),app.td_stp];
                sp_struct.brownian2comp = [max(y),max(y),mean(y(round((3*numel(y)/4)):numel(y))),1/2*app.td_stp,app.td2_stp];
                sp_struct.anomalous = [max(y)*2,mean(y(round((3*numel(y)/4)):numel(y))), app.td_stp, app.alpha_stp];
                sp_cell = struct2cell(sp_struct);
                start_points = sp_cell{app.type};
                
                % initial parameters
                initial_parameters = repmat(single(single(start_points)'), [1, app.number_fits]);
            
                % fit data
                data = single(y); data = repmat(data(:), [1, app.number_fits]);
            
                % user info (independent variables)
                user_info = single(x);
            
                % Run Gpufit
                [parameters, states, chi_squares, n_iterations, gputime] = gpufit(data, [], ...
                 model_id, initial_parameters, tolerance, max_n_iterations, [], estimator_id, user_info);
            
                % converged parameters
                converged = states == 0; 
                app.converged_parameters = parameters(:, converged);
            
                % use raw parameters if poor convergence
                if isempty(app.converged_parameters) == 1
                    model_coefs = parameters(1:number_parameters);
                else
                    model_coefs = app.converged_parameters(1:number_parameters);
                end
                
                len_x = numel(x);
                
                % construct fit result curve
                n_struct = struct('brownian',3,'brownian2comp',[4; 5],'anomalous',3);
                n_cell = struct2cell(n_struct);
                n = n_cell{app.type};
                
                if app.type == 1
                    model_fit(1:len_x) = model_coefs(1).* (1./(1+(x(1:len_x)./model_coefs(3)))) + model_coefs(2);
                elseif app.type == 2
                    model_fit(1:len_x) = model_coefs(1).* (1./(1+(x(1:len_x)./model_coefs(4)))) + model_coefs(2).* (1./(1+(x(1:len_x)./model_coefs(5)))) + model_coefs(3);
                elseif app.type == 3
                    model_fit(1:len_x) = model_coefs(1).* (1./(1+(x(1:len_x)./model_coefs(3)).^model_coefs(4))) + model_coefs(2); 
                end
                
                % R-Square
                residuals = y - model_fit;
                a = (y - model_fit).^2./model_fit;
                a(isinf(a)) = 0;
                rsquare = 1-sum(residuals.^2)/sum(y.^2);
                
                % fit result structure
                fitresult(1,i)=struct('rawdata',[x',y'],'rsquare',rsquare,'model_fit',model_fit);
                
                % characteristic time
                tauD(i)=model_coefs(n(1))*app.dT; % in in seconds
              
                % diffusion coefficient
                D(i)=(app.pixelsize.^2)/(4*tauD(i)); %in nm^2/s
                
                % second diffusion coefficient if using 2-component model
                if app.type == 2
                    tauD2(i)=model_coefs(n(2))*app.dT; % in in seconds
                    D2(i)=(app.pixelsize.^2)/(4*tauD(i));
                end
                
                % alpha map if using anomalous model
                if app.type == 3
                    alpha(i)= model_coefs(4);
                else
                    alpha(i)=0;
                end
                
                % compute total Gpufit time
                fit_time = fit_time + gputime;
            end
            
            %% Post fit data manipulation
            % reshape fit result 
            app.fitresult2=reshape(fitresult,rowdim,coldim);
            
            %Diffusion coefficient map
            app.Dmap=reshape([D],rowdim,coldim);
            app.Dmap = abs(app.Dmap);
            
            % alpha map (anomalous diffusion)
            if app.type == 3
                    alpha_corrected = zeros(1,numel(alpha));
                    % remove bad alphas
                    for i=1:numel(alpha)
                        if alpha(i) > app.alpha_thresh
                            alpha_corrected(i)=0;
                        elseif alpha(i) < 0
                            alpha_corrected(i)=0;
                        else
                            alpha_corrected(i)=abs(alpha(i));
                        end
                    end
                    app.alphamap=reshape(alpha_corrected,rowdim,coldim);
            end
            
            tauDmap=reshape([tauD],rowdim,coldim);
            
            % remove poor fits
            D_corrected = zeros(1,numel(D));
            for i=1:numel(D)
                if fitresult(1,i).rsquare<0.5
                    D_corrected(i)=0;
                else
                    D_corrected(i)=abs(D(i));
                end
            end
            app.Dmap_corrected=reshape([D_corrected],rowdim,coldim);
            
            % second diffusion coefficeint map if 2-component brownian model
            if app.type == 2
                app.D2map=reshape([D2],rowdim,coldim);
                tauD2map=reshape([tauD2],rowdim,coldim);
                
                D2_corrected = zeros(1,numel(D2));
            for i=1:numel(D2)
                if fitresult(1,i).rsquare<0.5
                    D2_corrected(i)=0;
                else
                    D2_corrected(i)=abs(D2(i));
                end
            end
            app.D2map_corrected=reshape([D2_corrected],rowdim,coldim);
            end
            
            % make map of R^2 values
            R2 = zeros(1,numel(fitresult));
            for i=1:numel(fitresult)
                R2(i)=fitresult(1,i).rsquare;
            end
            app.R2map=reshape(R2,rowdim,coldim);
            
            name = ["Brownian","2-Component Brownian","Anomalous"];name = name(app.type);
            
            Bin_after = clock;
            
            end
            
            app.ProgressTextArea.Value = sprintf('FCS complete'); drawnow
            
            
%%%%%%%%%%%%%%%%%%%% STEP 3: CombineTempSpat (fcs) %%%%%%%%%%%%%%%%%%%%%%%%%
            %% load data
            for index = 1:numel(app.trialnos)
            % load(strcat(app.parameter,num2str(app.trialnos(index)),'app.Dmap.mat'))
            % load(strcat(app.parameter,num2str(app.trialnos(index)),'analyzed.mat'))
            
            Combine_before = clock;
            %% Diffusion map
            app.szmap1=size(app.Dmap,1);
            app.szmap2=size(app.Dmap,2);
            
            app.Dmap2log=log10(app.Dmap); %actually use app.Dmap, unfiltered R^2; filter in this code
            app.D2map2log = log10(app.D2map);
            
            for i=1:size(app.Dmap2log,1)
                for j=1:size(app.Dmap2log,2)
                    if app.Dmap2log(i,j)==-Inf
                        app.Dmap2log(i,j)=0;
                    end
                end
            end
            
            %filter out poor fits if you want to here with an R2cutoff
            R2cutoff=0; %set R^2 cutoff (0-1); == 0, no filtering
            for i=1:size(app.Dmap2log,1)
                for j=1:size(app.Dmap2log,2)
                    if app.R2map(i,j)<R2cutoff
                        app.Dmap2log(i,j)=0;
                    end
                end
            end
            
            app.normcoef=10; %instead set to standard here
            app.normDmap2log=app.Dmap2log./app.normcoef;

%COLOR SCALING
            %set caxis limits for diffusion map
            % app.cmin=2; % USER INPUT set limits log(D) colorscale
            % app.cmax=6;
            for i=1:size(app.normDmap2log,1)
                for j=1:size(app.normDmap2log,2)
                    if app.normDmap2log(i,j)==0
                        app.normDmap2log(i,j)=0;
                    elseif app.normDmap2log(i,j)<app.cmin/app.normcoef
                        app.normDmap2log(i,j)=app.cmin/app.normcoef;
                    elseif app.normDmap2log(i,j)>app.cmax/app.normcoef
                        app.normDmap2log(i,j)=app.cmax/app.normcoef;
                    end
                end
            end
                 
            %% Create scaling/stretching/shifting factor to create colormap
            %changing shift, scale will change the colormap colors, range
            maxvalue=app.cmax/app.normcoef; %this is the max value on the colormap
            shift=0.0;%shift left right
            scale=0.7;%factor to multiply everything by
            for i=1:size(app.normDmap2log,1)
                for j=1:size(app.normDmap2log,2)
                    if app.normDmap2log(i,j)==0
                        app.normDmap2log(i,j)=0;
                    else
                        app.normDmap2log(i,j)=(app.normDmap2log(i,j)-shift).*scale;
                    end
                end
            end
            
            % %create colormap from the app.cmin/app.cmax
            ncmin=((app.cmin/app.normcoef)-shift).*scale;
            ncmax=((app.cmax/app.normcoef)-shift).*scale;
            steps=25; %number of steps between min/max
            cgrad=[ncmin:((ncmax-ncmin)/steps):ncmax]';%create gradient
            
            %set cmap the same way as diffusion map 
            cmap(1:steps+1,1,1)=cgrad; %set hue = color
            cmap(1:steps+1,1,2)=ones(steps+1,1); %set saturation ("gray")
            cmap(1:steps+1,1,3)=ones(steps+1,1); %set brightness
            rgb_cmap=1-hsv2rgb(cmap); %convert to rgb
            app.rgb_cmap_rs = [rgb_cmap(:,:,1) rgb_cmap(:,:,2) rgb_cmap(:,:,3)];
            
            
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,1)=app.normDmap2log; %set hue = color
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,2)=ones(app.szmap1,app.szmap2); %set saturation ("gray")
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.Dmap_hsv=1-hsv2rgb(app.Dmap_hsv); %convert to rgb
            for i=1:size(app.Dmap_hsv,1) %filter out background rgb(D)=0, then make pixel black
                for j=1:size(app.Dmap_hsv,2)
                    if app.Dmap_hsv(i,j,1)==0
                        app.Dmap_hsv(i,j,:)=0;
                    end
                end
            end
                        
            %% make super resolution only map
            % set caxis limits for SR map
            % cscaleACim=AC_im2;
            cscaleACim=app.filtim;
%             app.cminAC=0; %USER INPUT of SOFI image limits
%             app.cmaxAC=1e9;
            for i=1:size(cscaleACim,1) %filter out values above/below, change to limits
                for j=1:size(cscaleACim,2)
                    if cscaleACim(i,j)<app.cminAC
                        cscaleACim(i,j)=app.cminAC;
                    elseif cscaleACim(i,j)>app.cmaxAC
                        cscaleACim(i,j)=app.cmaxAC;
                    end
                end
            end
            app.norm_ca_AC=cscaleACim./(max(max(cscaleACim))); %normalize
            
            % HSV values
            app.srmap_hsv(1:app.szmap1,1:app.szmap2,1)=ones(app.szmap1,app.szmap2); %set hue = color
            % srmap_hsv(1:app.szmap1,1:app.szmap2,2)=AC_G2_im./(max(max(AC_G2_im))); %set saturation ("gray")
            % srmap_hsv(1:app.szmap1,1:app.szmap2,2)=AC_im2./(max(max(AC_im2))); %set saturation ("gray")
            app.srmap_hsv(1:app.szmap1,1:app.szmap2,2)=app.norm_ca_AC;
            app.srmap_hsv(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.srmap_hsv=1-hsv2rgb(app.srmap_hsv); %convert to rgb
            app.srmap_hsv=rgb2gray(app.srmap_hsv);% convert to gray scale
            
            %% combine D and Super res. for HSV
            hsvmap(1:app.szmap1,1:app.szmap2,1)=app.normDmap2log; %set hue = color
            hsvmap(1:app.szmap1,1:app.szmap2,2)=app.norm_ca_AC;
            hsvmap(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.hsv2rgbmap=1-hsv2rgb(hsvmap); %convert to rgb
            
            
            app.alphamap(app.srmap_hsv < 0.01) = 0;
            
            %% computation time
            Combine_after = clock;
%             app.ProgressTextArea.Value = {sprintf('blink_AConly complete, execution time: %6.2f seconds\n',etime(blink_after,blink_before)),...
%                                           sprintf('BinFitData complete, execution time: %6.2f seconds',etime(Bin_after,Bin_before)),...
%                                           sprintf('CombineTempSpat complete, execution time: %6.2f seconds',etime(Combine_after,Combine_before))};
            end
            

            %Default figure(s) (add more axes?)
            cla(app.UIAxes,'reset');
            imagesc(app.UIAxes,app.hsv2rgbmap) % combined
            axis image
            title(app.UIAxes,'Combined')
            c = colorbar(app.UIAxes,'Ticks',[min(app.hsv2rgbmap(:)), max(app.hsv2rgbmap(:))],'TickLabels',{num2str(app.cmin), num2str(app.cmax)});
            title(c, 'log(D)', 'FontSize', 10)  
            colormap(app.UIAxes,app.rgb_cmap_rs);

             
            close all


            if app.savethedata == 1 
                fit_curves = app.fitresult2;
                fit_parameters = double(app.converged_parameters);
                SOFI = app.srmap_hsv;
                D_map = app.Dmap;
                D_map_corrected = app.Dmap_corrected;
                Rsquare_map = app.R2map;
                fcsSOFI = app.hsv2rgbmap;
                fcsSOFI_cmap = app.rgb_cmap_rs;
                if app.type == 1
                    
                    save(strcat(app.fname,'_analyzed_brownian_',app.date),'fit_curves','fit_parameters','SOFI','D_map','D_map_corrected','Rsquare_map','fcsSOFI','fcsSOFI_cmap');
                    figfile = strcat(app.fname,'_figures_brownian_',app.date);
                elseif app.type == 2
                    D2_map = app.D2map;
                    D2_map_corrected = app.D2map_corrected;
                    save(strcat(app.fname,'_analyzed_2comp_brown_',app.date),'fit_curves','fit_parameters','SOFI','D_map','D_map_corrected','D2_map','D2_map_corrected','Rsquare_map','fcsSOFI','fcsSOFI_cmap');
                    figfile = strcat(app.fname,'_figures_2comp_brown_',app.date);
                elseif app.type == 3
                    alpha_map = app.alphamap;
                    save(strcat(app.fname,'_analyzed_anomalous_',app.date),'fit_curves','fit_parameters','SOFI','D_map','D_map_corrected','alpha_map','Rsquare_map','fcsSOFI','fcsSOFI_cmap');
                    figfile = strcat(app.fname,'_figures_anomalous_',app.date);
                end
            end      
            
%             if app.savefigures == 1
%                 mkdir(figfile)
%                 saveas(h,strcat(app.fname,'_SOFI_super_res_','_', name,'.png'));
%                 saveas(h2,strcat(app.fname,'_Rsquare_map_','_', name,'.png'));
%                 saveas(h3,strcat(app.fname,'_CombineTempSpat_','_', name,'.png'));
%                 
%                 
%                 saveas(figure(1),[pwd '/subFolderName/myFig.fig']);
%             end
%             
            %% total computation time
            app.totaltime = etime(clock,before);
            app.ProgressTextArea.Value = {sprintf('fcsSOFI analysis complete\n'),...
                              sprintf('SOFI execution time: %6.2f seconds\n',etime(blink_after,blink_before)),...
                              sprintf('FCS execution time: %6.2f seconds',etime(Bin_after,Bin_before)),...
                              sprintf('Image fusion execution time: %6.2f seconds',etime(Combine_after,Combine_before)),...
                              sprintf('Total fcsSOFI execution time: %6.2f seconds\n',app.totaltime)};
            
            % write computation time to text file for remote access
            DateString = datestr(datetime);
            xdim = num2str((app.xmax-app.xmin+1)); ydim = num2str((app.ymax-app.ymin+1));
            fid = fopen('fcsSOFI_Execution_Time.txt','at');
            fprintf(fid,'GUI\n');
            fprintf(fid, [DateString '\n']);
            fprintf(fid, ['Data File:' ' ' app.fname '.m' ' (' xdim 'x' ydim ' ' 'image)\n']); 
            fprintf(fid, ['Total execution time:' ' ' num2str(app.totaltime/60) ' ' 'minutes (' num2str(app.totaltime) ' ' 'seconds)\n']);
            fprintf(fid, ['Total time on GPU:' ' ' num2str(fit_time) ' ' 'seconds\n\n']);
            fclose(fid);
            
            close all
            app.browse = 0;


        end

        % Callback function: DiffusionModelButtonGroup, 
        % DisplayfiguresButtonGroup, FWHMEditField, 
        % FilenameextEditField, PixelsizenmEditField, 
        % SOFI_threshEditField, SavedataButtonGroup, 
        % TrialnumbersEditField, alpha_stpEditField, 
        % alpha_threshEditField, cmaxACEditField, cmaxEditField, 
        % cminACEditField, cminEditField, dTsEditField, 
        % td2_stpEditField, td_stpEditField, tmaxEditField, 
        % tminEditField, xmaxEditField, xminEditField, 
        % ymaxEditField, yminEditField
        function EditFieldValueChanged(app, event)
            % CALLBACK FUNCTION FOR ALL GUI COMPONENTS
            
            %File name ext.
            app.parameter = app.FilenameextEditField.Value;
            
            %Trial number(s)
            app.trialnos = app.TrialnumbersEditField.Value;
            
            %Pixel size
            app.pixelsize = app.PixelsizenmEditField.Value;
            
            %dT
            app.dT = app.dTsEditField.Value;
            
            %FWHM (PSF)
            app.FWHM = app.FWHMEditField.Value;
            
%             %Min scale
%             app.minScale = app.MinscaleEditField.Value;
%             
%             %Max scale
%             app.maxScale = app.MaxscaleEditField.Value;
            
            %caxis limits 
            app.cmin = app.cminEditField.Value;
            app.cmax = app.cmaxEditField.Value;
            
            %SOFI saturation limits
            app.cminAC = app.cminACEditField;
            app.cmaxAC = app.cmaxACEditField;
            
            %x min
            app.xmin = app.xminEditField.Value;
            
            %x max
            app.xmax = app.xmaxEditField.Value;
            
            %y min
            app.ymin = app.yminEditField.Value;
            
            %y max
            app.ymax =  app.ymaxEditField.Value;
            
            %t min
            app.tmin = app.tminEditField.Value;
            
            %t max
            app.tmax = app.tmaxEditField.Value;
            
            %tauD1 start point
            app.td_stp = app.td_stpEditField.Value;
            
            %tauD2 start point
            app.td2_stp = app.td2_stpEditField.Value;
            
            %Alpha start point
            app.alpha_stp = app.alpha_stpEditField.Value;
            
            %alpha threshold
            app.alpha_thresh = app.alpha_threshEditField.Value;
            
            %SOFI response threshold
            app.SOFI_thresh = app.SOFI_threshEditField.Value;


            %Diffusion Type
            switch app.DiffusionModelButtonGroup.SelectedObject.Text
                case 'Brownian'
                    app.type = 1;  
                case '2-Comp Brownian'
                    app.type = 2;
                case 'Anomalous'
                    app.type = 3;  
            end
            
            %Display figures?
            switch app.DisplayfiguresButtonGroup.SelectedObject.Text
                case 'Yes'
                    app.savefigures = 1;  
                case 'No'
                    app.savefigures = 0;
            end
            
            %Save data?
            switch app.SavedataButtonGroup.SelectedObject.Text
                case 'Yes'
                    app.savethedata = 1;  
                case 'No'
                    app.savethedata = 0;
            end


        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            for j = 1:numel(app.trialnos)
                app.fname = strcat(app.parameter,num2str(app.trialnos(j)));
            
                % Load data
                load(app.fname); app.ProgressTextArea.Value = sprintf(strcat(app.fname,'.mat loaded\n'));
                
                app.DataCombined=Data;
            
                % produce average image
                app.avgimage=sum(app.DataCombined(:,:,:),3)./size(app.DataCombined,3);
                
                imagesc(app.UIAxes,app.avgimage(:,:,1))
                axis image
                title(app.UIAxes,'First Frame')
                set(gca,'xtick',[],'ytick',[])
                app.browse = 0;
                close all;
            end
        end

        % Button pushed function: DisplayROIButton
        function DisplayROIButtonPushed(app, event)

            % Set ROI
%             app.DataCombined=app.DataCombined(app.ymin:app.ymax,app.xmin:app.xmax,app.tmin:app.tmax);
%             size(app.DataCombined);
%                 
%             % produce average image
%             app.avgimage=sum(app.DataCombined(:,:,:),3)./size(app.DataCombined,3);

            cla(app.UIAxes,'reset');  
            imagesc(app.UIAxes,app.avgimage(app.xmin:app.xmax,app.ymin:app.ymax,1))  
            ax = app.UIAxes;
%             ax.style = 'image';  
            title(app.UIAxes,'First Frame at Selected ROI')
            set(gca,'xtick',[],'ytick',[])
            close all
            
        end

        % Value changed function: DisplayFigureDropDown
        function DisplayFigureDropDownValueChanged(app, event)
        %% INPUT ADDITIONAL FIGURES HERE
            switch app.DisplayFigureDropDown.Value
                
                case 'Combined fcsSOFI Image'
                    cla(app.UIAxes,'reset');
                    imagesc(app.UIAxes,app.hsv2rgbmap) % combined
                    axis image
                    title(app.UIAxes,'Combined')
                    c = colorbar(app.UIAxes,'Ticks',[min(app.hsv2rgbmap(:)), max(app.hsv2rgbmap(:))],'TickLabels',{num2str(app.cmin), num2str(app.cmax)});
                    title(c, 'log(D)', 'FontSize', 10)  
                    colormap(app.UIAxes,app.rgb_cmap_rs);
                    close all
                
                case 'Diffraction-Limited Image'
                    cla(app.UIAxes,'reset');
                    imagesc(app.UIAxes,app.avgimage(:,:,1))
                    axis image
                    title(app.UIAxes,'Average Diffraction-Limited Image')
                    close all
                
                case 'SOFI Image'
                    cla(app.UIAxes,'reset');
                    imagesc(app.UIAxes,app.srmap_hsv);
                    axis image
                    title(app.UIAxes,'SOFI super-resolution')
%                   colormap(app.UIAxes,gray)   
                    close all
               
                case 'R-square Map'
                    cla(app.UIAxes,'reset');
                    imagesc(app.UIAxes,app.R2map)
                    caxis(app.UIAxes,[0 1]);
                    axis image
                    title(app.UIAxes,'R^2 map');
                    c = colorbar(app.UIAxes);
                    title(c, 'R-Square', 'FontSize', 10)
                    close all
                    
                case 'alpha map'    
                    cla(app.UIAxes,'reset');
                    imagesc(app.UIAxes,app.alphamap);
                    caxis(app.UIAxes,[0 max(max((app.alphamap)))]);
                    axis image;
                    title('\alpha map');
                    c=colorbar(app.UIAxes);
                    title(c,'\alpha','FontSize',20);
                    set(gca,'xtick',[],'ytick',[]);
                    close all
                    
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.browse = 1;
            app.trialnos = 1;
            app.parameter = [];
             [app.browsed_file,path] = uigetfile('*.mat')  %open a mat file
             % Load data
             
             load(app.browsed_file); app.ProgressTextArea.Value = sprintf(strcat(app.browsed_file,' loaded\n'));
             app.DataCombined=Data;
            
             % produce average image
             app.avgimage=sum(app.DataCombined(:,:,:),3)./size(app.DataCombined,3);
             
             cla(app.UIAxes,'reset');
             imagesc(app.UIAxes,app.avgimage(:,:,1))
             ax = app.UIAxes;
%              ax.style = 'image';
             title(app.UIAxes,'First Frame')
             set(gca,'xtick',[],'ytick',[])
             close all;
        end

        % Callback function: ColumnIndexEditField, 
        % DisplaySinglePixelCurveFitButton, RowIndexEditField
        function DisplayButtonPushed(app, event)
        app.row_index = app.RowIndexEditField.Value;
        app.column_index = app.ColumnIndexEditField.Value;
        
        x2=app.fitresult2(app.row_index,app.column_index).rawdata(:,1);
        y2=app.fitresult2(app.row_index,app.column_index).rawdata(:,2);
        N = max(y2);
        
        cla(app.UIAxes,'reset');
        ax = app.UIAxes;
        semilogx(app.UIAxes,x2,1/N.*y2,'or','LineWidth',2)
        hold(app.UIAxes,'on') 
        semilogx(app.UIAxes,x2',1/N.*app.fitresult2(app.row_index,app.column_index).model_fit,'--k','LineWidth',2)
        ax.YDir = 'normal'; ax.XMinorTick = 'on'; ax.YMinorTick = 'on';
        xlabel(app.UIAxes,'\tau')
        ylabel(app.UIAxes,'G(\tau)')
        legend(app.UIAxes,'Raw Data','Fit Result');
        title(app.UIAxes,['Curve Fit for Pixel ' '(' num2str(app.row_index) ',' num2str(app.column_index ) ')']); 
        hold(app.UIAxes,'off')
        close all
        
        fprintf('\nPixel (%i,%i):',app.row_index,app.column_index );
        
        if app.type == 2
        app.SinglePixelTextArea.Value = {sprintf('Pixel (%i,%i)\n\n',app.row_index,app.column_index ),...
                              sprintf('D1:         %6.3e\n',app.Dmap_corrected(app.row_index,app.column_index )),...
                              sprintf('D2:         %6.3e\n',app.D2map_corrected(app.row_index,app.column_index )),...
                              sprintf('log10(D1):  %6.3f\n',app.Dmap2log(app.row_index,app.column_index )),...
                              sprintf('log10(D2):  %6.3f\n\n',app.D2map2log(app.row_index,app.column_index )),...
                              sprintf('R-square:  %6.4f',app.R2map(app.row_index,app.column_index ))};   
        
        elseif app.type == 3
        app.SinglePixelTextArea.Value = {sprintf('Pixel (%i,%i)\n\n',app.row_index,app.column_index ),...
                              sprintf('D:         %6.3e\n',app.Dmap_corrected(app.row_index,app.column_index )),...
                              sprintf('log10(D):  %6.3f\n\n',app.Dmap2log(app.row_index,app.column_index )),...
                              sprintf('alpha:     %6.4f\n\n',app.alphamap(app.row_index,app.column_index )),...
                              sprintf('R-square:  %6.4f',app.R2map(app.row_index,app.column_index ))};
                          
                
        else
        app.SinglePixelTextArea.Value = {sprintf('Pixel (%i,%i)\n\n',app.row_index,app.column_index ),...
                              sprintf('D:         %6.3e\n',app.Dmap_corrected(app.row_index,app.column_index )),...
                              sprintf('log10(D):  %6.3f\n\n',app.Dmap2log(app.row_index,app.column_index )),...
                              sprintf('R-square:  %6.4f',app.R2map(app.row_index,app.column_index ))};
        end
        end

        % Button pushed function: RedrawfcsSOFIButton
        function RedrawfcsSOFIButtonPushed(app, event)
           
            clear ap.szmap1 app.szmap2 app.Dmap2log app.normDmap2log 
            %caxis limits 
            app.cmin = app.cminEditField.Value;
            app.cmax = app.cmaxEditField.Value;
            
            %SOFI saturation limits
            app.cminAC = app.cminACEditField;
            app.cmaxAC = app.cmaxACEditField; 
       
            %% Diffusion map
            app.szmap1=size(app.Dmap,1);
            app.szmap2=size(app.Dmap,2);
            
            app.Dmap2log=log10(app.Dmap); %actually use app.Dmap, unfiltered R^2; filter in this code
            app.D2map2log = log10(app.D2map);
            
            for i=1:size(app.Dmap2log,1)
                for j=1:size(app.Dmap2log,2)
                    if app.Dmap2log(i,j)==-Inf
                        app.Dmap2log(i,j)=0;
                    end
                end
            end
            
            %filter out poor fits if you want to here with an R2cutoff
            R2cutoff=0; %set R^2 cutoff (0-1); == 0, no filtering
            for i=1:size(app.Dmap2log,1)
                for j=1:size(app.Dmap2log,2)
                    if app.R2map(i,j)<R2cutoff
                        app.Dmap2log(i,j)=0;
                    end
                end
            end    
            
            app.normDmap2log=app.Dmap2log./app.normcoef;


            for i=1:size(app.normDmap2log,1)
                for j=1:size(app.normDmap2log,2)
                    if app.normDmap2log(i,j)==0
                        app.normDmap2log(i,j)=0;
                    elseif app.normDmap2log(i,j)<app.cmin/app.normcoef
                        app.normDmap2log(i,j)=app.cmin/app.normcoef;
                    elseif app.normDmap2log(i,j)>app.cmax/app.normcoef
                        app.normDmap2log(i,j)=app.cmax/app.normcoef;
                    end
                end
            end
                 
            %% Create scaling/stretching/shifting factor to create colormap
            %changing shift, scale will change the colormap colors, range
            maxvalue=app.cmax/app.normcoef; %this is the max value on the colormap
            shift=0.0;%shift left right
            scale=0.7;%factor to multiply everything by
            for i=1:size(app.normDmap2log,1)
                for j=1:size(app.normDmap2log,2)
                    if app.normDmap2log(i,j)==0
                        app.normDmap2log(i,j)=0;
                    else
                        app.normDmap2log(i,j)=(app.normDmap2log(i,j)-shift).*scale;
                    end
                end
            end
            
            % %create colormap from the app.cmin/app.cmax
            ncmin=((app.cmin/app.normcoef)-shift).*scale;
            ncmax=((app.cmax/app.normcoef)-shift).*scale;
            steps=25; %number of steps between min/max
            cgrad=[ncmin:((ncmax-ncmin)/steps):ncmax]';%create gradient
            
            %set cmap the same way as diffusion map 
            cmap(1:steps+1,1,1)=cgrad; %set hue = color
            cmap(1:steps+1,1,2)=ones(steps+1,1); %set saturation ("gray")
            cmap(1:steps+1,1,3)=ones(steps+1,1); %set brightness
            rgb_cmap=1-hsv2rgb(cmap); %convert to rgb
            app.rgb_cmap_rs = [rgb_cmap(:,:,1) rgb_cmap(:,:,2) rgb_cmap(:,:,3)];
            
            
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,1)=app.normDmap2log; %set hue = color
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,2)=ones(app.szmap1,app.szmap2); %set saturation ("gray")
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.Dmap_hsv=1-hsv2rgb(app.Dmap_hsv); %convert to rgb
            for i=1:size(app.Dmap_hsv,1) %filter out background rgb(D)=0, then make pixel black
                for j=1:size(app.Dmap_hsv,2)
                    if app.Dmap_hsv(i,j,1)==0
                        app.Dmap_hsv(i,j,:)=0;
                    end
                end
            end
            
            %% make super resolution only map
            % set caxis limits for SR map
            % cscaleACim=AC_im2;
            cscaleACim=app.filtim;
            for i=1:size(cscaleACim,1) %filter out values above/below, change to limits
                for j=1:size(cscaleACim,2)
                    if cscaleACim(i,j)<app.cminAC
                        cscaleACim(i,j)=app.cminAC;
                    elseif cscaleACim(i,j)>app.cmaxAC
                        cscaleACim(i,j)=app.cmaxAC;
                    end
                end
            end
            app.norm_ca_AC=cscaleACim./(max(max(cscaleACim))); %normalize
            
            % HSV values
            app.srmap_hsv(1:app.szmap1,1:app.szmap2,1)=ones(app.szmap1,app.szmap2); %set hue = color
            % srmap_hsv(1:app.szmap1,1:app.szmap2,2)=AC_G2_im./(max(max(AC_G2_im))); %set saturation ("gray")
            % srmap_hsv(1:app.szmap1,1:app.szmap2,2)=AC_im2./(max(max(AC_im2))); %set saturation ("gray")
            app.srmap_hsv(1:app.szmap1,1:app.szmap2,2)=app.norm_ca_AC;
            app.srmap_hsv(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.srmap_hsv=1-hsv2rgb(app.srmap_hsv); %convert to rgb
            app.srmap_hsv=rgb2gray(app.srmap_hsv);% convert to gray scale
            
            %% combine D and Super res. for HSV
            hsvmap(1:app.szmap1,1:app.szmap2,1)=app.normDmap2log; %set hue = color
            hsvmap(1:app.szmap1,1:app.szmap2,2)=app.norm_ca_AC;
            hsvmap(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.hsv2rgbmap=1-hsv2rgb(hsvmap); %convert to rgb
                                
                    
             %Redraw Figure       
            cla(app.UIAxes,'reset');
            imagesc(app.UIAxes,app.hsv2rgbmap) % combined
            axis image
            title(app.UIAxes,'Combined')
            c = colorbar(app.UIAxes,'Ticks',[min(app.hsv2rgbmap(:)), max(app.hsv2rgbmap(:))],'TickLabels',{num2str(app.cmin), num2str(app.cmax)});
            title(c, 'log(D)', 'FontSize', 10)  
            colormap(app.UIAxes,app.rgb_cmap_rs);
            close all

            % Save new map
            if app.savethedata == 1 
                fit_curves = app.fitresult2;
                fit_parameters = double(app.converged_parameters);
                SOFI = app.srmap_hsv;
                D_map = app.Dmap;
                D_map_corrected = app.Dmap_corrected;
                Rsquare_map = app.R2map;
                fcsSOFI = app.hsv2rgbmap;
                fcsSOFI_cmap = app.rgb_cmap_rs;
                if app.type == 1
                    save(strcat(app.fname,'_analyzed_brownian_',app.date),'fit_curves','fit_parameters','SOFI','D_map','D_map_corrected','Rsquare_map','fcsSOFI','fcsSOFI_cmap');
                    figfile = strcat(app.fname,'_figures_brownian_',app.date);
                elseif app.type == 2
                    D2_map = app.D2map;
                    D2_map_corrected = app.D2map_corrected;
                    save(strcat(app.fname,'_analyzed_2comp_brown_',app.date),'fit_curves','fit_parameters','SOFI','D_map','D_map_corrected','D2_map','D2_map_corrected','Rsquare_map','fcsSOFI','fcsSOFI_cmap');
                    figfile = strcat(app.fname,'_figures_2comp_brown_',app.date);
                elseif app.type == 3
                    alpha_map = app.alphamap;
                    save(strcat(app.fname,'_analyzed_anomalous_',app.date),'fit_curves','fit_parameters','SOFI','D_map','D_map_corrected','alpha_map','Rsquare_map','fcsSOFI','fcsSOFI_cmap');
                    figfile = strcat(app.fname,'_figures_anomalous_',app.date);
                end
            end   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FilenameextEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameextEditFieldLabel.Text = 'File name ext.';
            app.TrialnumbersEditFieldLabel = uilabel(app.UIFigure);
            app.TrialnumbersEditFieldLabel.Text = 'Trial number(s)';
            app.FilenameextEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameextEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.FilenameextEditField.Value = 'dataset';
            app.TrialnumbersEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialnumbersEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.TrialnumbersEditField.Value = 77;
            app.PixelsizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size (nm)';
            app.PixelsizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsizenmEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.PixelsizenmEditField.Value = 50;
            app.dTsEditFieldLabel = uilabel(app.UIFigure);
            app.dTsEditFieldLabel.Text = 'dT (s)';
            app.dTsEditField = uieditfield(app.UIFigure, 'numeric');
            app.dTsEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.dTsEditField.Value = 0.04;
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.UIFigure, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.xminEditField.Value = 1;
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.xmaxEditField.Value = 30;
            app.yminEditField_3Label = uilabel(app.UIFigure);
            app.yminEditField_3Label.Text = 'y min';
            app.yminEditField = uieditfield(app.UIFigure, 'numeric');
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.yminEditField.Value = 1;
            app.ymaxEditFieldLabel = uilabel(app.UIFigure);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ymaxEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ymaxEditField.Value = 30;
            app.tminEditFieldLabel = uilabel(app.UIFigure);
            app.tminEditFieldLabel.Text = 't min';
            app.tminEditField = uieditfield(app.UIFigure, 'numeric');
            app.tminEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.tminEditField.Value = 1;
            app.tmaxEditFieldLabel = uilabel(app.UIFigure);
            app.tmaxEditFieldLabel.Text = 't max';
            app.tmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.tmaxEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.tmaxEditField.Value = 5000;
            app.DiffusionModelButtonGroup = uibuttongroup(app.UIFigure);
            app.DiffusionModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DiffusionModelButtonGroup.Title = 'Diffusion Model';
            app.BrownianButton = uitogglebutton(app.DiffusionModelButtonGroup);
            app.BrownianButton.Text = 'Brownian';
            app.BrownianButton.Value = true;
            app.CompBrownianButton = uitogglebutton(app.DiffusionModelButtonGroup);
            app.CompBrownianButton.Text = '2-Comp Brownian';
            app.AnomalousButton = uitogglebutton(app.DiffusionModelButtonGroup);
            app.AnomalousButton.Text = 'Anomalous';
            app.DisplayfiguresButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayfiguresButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DisplayfiguresButtonGroup.Title = 'Display figures?';
            app.YesButton = uitogglebutton(app.DisplayfiguresButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uitogglebutton(app.DisplayfiguresButtonGroup);
            app.NoButton.Text = 'No';
            app.SavedataButtonGroup = uibuttongroup(app.UIFigure);
            app.SavedataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SavedataButtonGroup.Title = 'Save data?';
            app.YesButton_2 = uitogglebutton(app.SavedataButtonGroup);
            app.YesButton_2.Text = 'Yes';
            app.YesButton_2.Value = true;
            app.NoButton_2 = uitogglebutton(app.SavedataButtonGroup);
            app.NoButton_2.Text = 'No';
            app.MicroscopyParametersLabel = uilabel(app.UIFigure);
            app.MicroscopyParametersLabel.Text = 'Microscopy Parameters';
            app.RegionofInterestinPixelsLabel = uilabel(app.UIFigure);
            app.RegionofInterestinPixelsLabel.Text = 'Region of Interest in Pixels';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.fcsSOFILabel = uilabel(app.UIFigure);
            app.fcsSOFILabel.Text = 'fcsSOFI';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LoadDataLabel = uilabel(app.UIFigure);
            app.LoadDataLabel.Text = 'Load Data';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DisplayROIButton = uibutton(app.UIFigure, 'push');
            app.DisplayROIButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayROIButtonPushed, true);
            app.DisplayROIButton.Text = 'Display ROI';
            app.DisplayFigureDropDownLabel = uilabel(app.UIFigure);
            app.DisplayFigureDropDownLabel.Text = 'Display Figure';
            app.DisplayFigureDropDown = uidropdown(app.UIFigure);
            app.DisplayFigureDropDown.Items = {'Combined fcsSOFI Image', 'Diffraction-Limited Image', 'SOFI Image', 'R-square Map', 'alpha map'};
            app.DisplayFigureDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayFigureDropDownValueChanged, true);
            app.DisplayFigureDropDown.Value = 'Combined fcsSOFI Image';
            app.ProgressTextArea = uitextarea(app.UIFigure);
            app.ProgressTextArea.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ManualLoadLabel = uilabel(app.UIFigure);
            app.ManualLoadLabel.Text = 'Manual Load';
            app.RowIndexEditFieldLabel = uilabel(app.UIFigure);
            app.RowIndexEditFieldLabel.Text = 'Row Index';
            app.RowIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowIndexEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.RowIndexEditField.Value = 15;
            app.ColumnIndexEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnIndexEditFieldLabel.Text = 'Column Index';
            app.ColumnIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnIndexEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.ColumnIndexEditField.Value = 15;
            app.DisplaySinglePixelCurveFitButton = uibutton(app.UIFigure, 'push');
            app.DisplaySinglePixelCurveFitButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplaySinglePixelCurveFitButton.Text = 'Display Single Pixel Curve Fit';
            app.SinglePixelFitResultsLabel = uilabel(app.UIFigure);
            app.SinglePixelFitResultsLabel.Text = 'Single-Pixel Fit Results';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SinglePixelTextArea = uitextarea(app.UIFigure);
            app.Version1630March2020Label = uilabel(app.UIFigure);
            app.Version1630March2020Label.Text = 'Version 1.6 (30 March 2020)';
            app.minEditFieldLabel = uilabel(app.UIFigure);
            app.minEditFieldLabel.Text = 'min';
            app.cminEditField = uieditfield(app.UIFigure, 'text');
            app.cminEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.cminEditField.Value = '2';
            app.maxEditFieldLabel = uilabel(app.UIFigure);
            app.maxEditFieldLabel.Text = 'max';
            app.cmaxEditField = uieditfield(app.UIFigure, 'text');
            app.cmaxEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.cmaxEditField.Value = '6';
            app.CaxisLimitsfcsSOFIColorScalingLabel = uilabel(app.UIFigure);
            app.CaxisLimitsfcsSOFIColorScalingLabel.Text = 'C-axis Limits (fcsSOFI Color Scaling)';
            app.SOFISaturationLimitsLabel = uilabel(app.UIFigure);
            app.SOFISaturationLimitsLabel.Text = 'SOFI Saturation Limits';
            app.minEditField_2Label = uilabel(app.UIFigure);
            app.minEditField_2Label.Text = 'min';
            app.cminACEditField = uieditfield(app.UIFigure, 'text');
            app.cminACEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.cminACEditField.Value = '0';
            app.maxEditField_2Label = uilabel(app.UIFigure);
            app.maxEditField_2Label.Text = 'max';
            app.cmaxACEditField = uieditfield(app.UIFigure, 'text');
            app.cmaxACEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.cmaxACEditField.Value = '1e9';
            app.ModelParameterSettingsLabel = uilabel(app.UIFigure);
            app.ModelParameterSettingsLabel.Text = 'Model Parameter Settings';
            app.alphastartpointLabel = uilabel(app.UIFigure);
            app.alphastartpointLabel.Text = 'alpha start point**';
            app.alpha_stpEditField = uieditfield(app.UIFigure, 'text');
            app.alpha_stpEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.alpha_stpEditField.Value = '1';
            app.maxiumalphaallowedLabel = uilabel(app.UIFigure);
            app.maxiumalphaallowedLabel.Text = 'maxium alpha allowed**';
            app.alpha_threshEditField = uieditfield(app.UIFigure, 'text');
            app.alpha_threshEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.alpha_threshEditField.Value = '2';
            app.SOFIresponsethresholdLabel = uilabel(app.UIFigure);
            app.SOFIresponsethresholdLabel.Text = 'SOFI response threshold**';
            app.SOFI_threshEditField = uieditfield(app.UIFigure, 'text');
            app.SOFI_threshEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SOFI_threshEditField.Value = '0.01';
            app.EditField_7Label_2 = uilabel(app.UIFigure);
            app.EditField_7Label_2.Text = 'tauD start point';
            app.td_stpEditField = uieditfield(app.UIFigure, 'text');
            app.td_stpEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.td_stpEditField.Value = '0.368';
            app.EditField_7Label_3 = uilabel(app.UIFigure);
            app.EditField_7Label_3.Text = 'tauD2 start point*';
            app.td2_stpEditField = uieditfield(app.UIFigure, 'text');
            app.td2_stpEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.td2_stpEditField.Value = '0.184';
            app.AlphaMapSettingsLabel = uilabel(app.UIFigure);
            app.AlphaMapSettingsLabel.Text = 'Alpha Map Settings**';
            app.AnomalousmodelonlyLabel = uilabel(app.UIFigure);
            app.AnomalousmodelonlyLabel.Text = '**Anomalous model only';
            app.CompmodelonlyLabel = uilabel(app.UIFigure);
            app.CompmodelonlyLabel.Text = '*2-Comp model only';
            app.RedrawfcsSOFIButton = uibutton(app.UIFigure, 'push');
            app.RedrawfcsSOFIButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawfcsSOFIButtonPushed, true);
            app.RedrawfcsSOFIButton.Text = 'Redraw fcsSOFI';
            app.FWHMofPSFpixelsLabel = uilabel(app.UIFigure);
            app.FWHMofPSFpixelsLabel.Text = 'FWHM of PSF (pixels)';
            app.FWHMEditField = uieditfield(app.UIFigure, 'numeric');
            app.FWHMEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.FWHMEditField.Value = 2.7;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fcsSOFI_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end